classdef EVCS2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        GridLayout2        matlab.ui.container.GridLayout
        GridLayout3        matlab.ui.container.GridLayout
        ProfileInfoPanel   matlab.ui.container.Panel
        GridLayout5        matlab.ui.container.GridLayout
        bEditFieldLabel    matlab.ui.control.Label
        bEditField         matlab.ui.control.NumericEditField
        BMEditFieldLabel   matlab.ui.control.Label
        BMEditField        matlab.ui.control.NumericEditField
        FLEditFieldLabel   matlab.ui.control.Label
        FLEditField        matlab.ui.control.NumericEditField
        sEditFieldLabel    matlab.ui.control.Label
        sEditField         matlab.ui.control.NumericEditField
        cnlEditFieldLabel  matlab.ui.control.Label
        cnlEditField       matlab.ui.control.NumericEditField
        cnrEditFieldLabel  matlab.ui.control.Label
        cnrEditField       matlab.ui.control.NumericEditField
        fnlEditFieldLabel  matlab.ui.control.Label
        fnlEditField       matlab.ui.control.NumericEditField
        fnrEditFieldLabel  matlab.ui.control.Label
        fnrEditField       matlab.ui.control.NumericEditField
        DataInputPanel     matlab.ui.container.Panel
        GridLayout6        matlab.ui.container.GridLayout
        NoLabel            matlab.ui.control.Label
        DLabel             matlab.ui.control.Label
        LRLabel            matlab.ui.control.Label
        RRLabel            matlab.ui.control.Label
        Label              matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        Label_3            matlab.ui.control.Label
        Label_4            matlab.ui.control.Label
        Label_5            matlab.ui.control.Label
        Label_6            matlab.ui.control.Label
        EditField          matlab.ui.control.NumericEditField
        Label_8            matlab.ui.control.Label
        Label_9            matlab.ui.control.Label
        Label_10           matlab.ui.control.Label
        EditField_2        matlab.ui.control.NumericEditField
        EditField_3        matlab.ui.control.NumericEditField
        EditField_5        matlab.ui.control.NumericEditField
        EditField_6        matlab.ui.control.NumericEditField
        EditField_7        matlab.ui.control.NumericEditField
        EditField_9        matlab.ui.control.NumericEditField
        EditField_10       matlab.ui.control.NumericEditField
        EditField_11       matlab.ui.control.NumericEditField
        EditField_13       matlab.ui.control.NumericEditField
        EditField_14       matlab.ui.control.NumericEditField
        EditField_15       matlab.ui.control.NumericEditField
        EditField_17       matlab.ui.control.NumericEditField
        EditField_18       matlab.ui.control.NumericEditField
        EditField_19       matlab.ui.control.NumericEditField
        EditField_21       matlab.ui.control.NumericEditField
        EditField_22       matlab.ui.control.NumericEditField
        EditField_23       matlab.ui.control.NumericEditField
        EditField_25       matlab.ui.control.NumericEditField
        ChainagePanel      matlab.ui.container.Panel
        GridLayout7        matlab.ui.container.GridLayout
        AddButton          matlab.ui.control.Button
        ListBox            matlab.ui.control.ListBox
        DeleteButton       matlab.ui.control.Button
        GridLayout4        matlab.ui.container.GridLayout
        LoadButton         matlab.ui.control.Button
        SaveButton         matlab.ui.control.Button
        SectionButton      matlab.ui.control.Button
        ReportButton       matlab.ui.control.Button
        ContourButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        FLinter = "linear"
        GLinter = "linear"
        csvData % Data Loaded From CSV File
        currentChainageContext
        contextLength = 0
        currentChainage = 1
        inputContext
        firstTimeRun = true
        maxChainageAmount = 150
        dt = 0.15
        dx = 0.15
        dy = 1
        Vcut=0
        Vfill =0
        contourInterp = 'spline'
        contourDensity = 200
        contourLines = 25
        contourRange = 40 % percent
        chainageContext     % Chainage Context Structure
        % [Ch, b,FL,BM, cnl, cnr, fnl, fnr, s, CL, D,LR, RR ]
        
    end
    
    
    methods (Access = private)
        
        function addNewChainage(app)
            app.firstRun();
            prompt = {'Chainage Distance:'};
            dlgtitle = 'New Chainage';
            fieldsize = [1 45];
            item = inputdlg(prompt,dlgtitle,fieldsize);


            l = length(app.ListBox.Items);
            app.ListBox.Items(l+1) = item;
            app.GridLayout6.Visible = true;
            
            ch = str2double(item)
            app.contextLength = app.contextLength +1;
          
            disp(app.chainageContext)
            app.chainageContext(app.contextLength,1) = ch;
            
            %app.loadChainage(app.contextLength);
            
            
        end
        
        
        %%%%%%%%%%%%%%%%%%%%%%
        function deleteChainage(app,item)
            [~,idx] = ismember(item,app.ListBox.Items);
            %app.ListBox.Value = [];
            disp("Delete: ")
            disp(idx)
            if idx
                app.ListBox.Items(idx) = [];
            end
            
            % after deleting matrix is to be corrected
            app.chainageContext(idx,:) = [];
            app.contextLength = app.contextLength-1;
            %disp(app.chainageContext)
      
            
        end
        
        function in = inputSingle(app,prompt,title)
            prompt = {prompt};
            in = inputdlg(prompt,title);
        end
        
        function loadChainage(app, index)
            disp("load chainage index")
            disp(index)
            for i=1:length(app.inputContext)
                app.inputContext(i).Value = app.chainageContext(index,i+1);
            end
            
        end
        
        function  setReading(app,no,type,val)
            if type == 'D'
                app.currentChainageContext(1+8+1+no) = val;
            elseif type == 'L'
                app.currentChainageContext(1+8+1+8+no) = val;
            elseif type == 'R'
                app.currentChainageContext(1+8+1+8+8+no) = val;
            end
            if app.currentChainage ~= 0
                app.chainageContext(app.currentChainage) = app.currentChainageContext;
            end
        end
        
        function  val = getReading(app,no,type)
            if type == 'D'
                val = app.currentChainageContext(1+8+1+no);
            elseif type == 'L'
                val = app.currentChainageContext(1+8+1+8+no);
            elseif type == 'R'
                val = app.currentChainageContext(1+8+1+8+8+no);
            end
        end
        
        function onEditFieldValueChanged(app,event)
            [~, idx] = ismember(event.Source,app.inputContext);
            disp("EditFieldIndex: ")
            display(idx);
            display(app.currentChainage);
            display(event);
            if idx
                app.chainageContext(app.currentChainage,idx+1) = event.Value;
            end
        end
        
        function clearListBox(app)
            app.ListBox.Items = {};
        end
        
        function firstRun(app)
            if app.firstTimeRun
                
                app.chainageContext = zeros(app.maxChainageAmount,28);
                app.firstTimeRun = false;
                app.DataInputPanel.Visible = true;
                % initialize input context
                app.inputContext = [app.bEditField,app.FLEditField,app.BMEditField,app.cnlEditField,app.cnrEditField,app.fnlEditField,app.fnrEditField,app.sEditField,app.EditField,app.EditField_2,app.EditField_6,app.EditField_10,app.EditField_14,app.EditField_18,app.EditField_22,app.EditField_3,app.EditField_7,app.EditField_11,app.EditField_15,app.EditField_19,app.EditField_23,app.EditField_5,app.EditField_9,app.EditField_13,app.EditField_17,app.EditField_21,app.EditField_25];
                %app.inputContext = app.inputContext + [app.EditField_2,app.EditField_6,app.EditField_10,app.EditField_14,app.EditField_18,app.EditField_22]; % 2,6,10,14,18,22
                %app.inputContext = app.inputContext + [app.EditField_3,app.EditField_7,app.EditField_11,app.EditField_15,app.EditField_19,app.EditField_23]; % 3 7 11 15 19 23
                %app.inputContext = app.inputContext + [app.EditField_5,app.EditField_9,app.EditField_13,app.EditField_17,app.EditField_21,app.EditField_25]; % 5 9 13 17 21 25
                
                for i = 1:length(app.inputContext)
                    app.inputContext(i).ValueChangedFcn = createCallbackFcn(app, @onEditFieldValueChanged, true);
                end
                
            end
            
        end
        
        function a = ch_data(app,index,y,isBM)
            if nargin < 4
                isBM = false;
            end
            
            CH =app.chainageContext(1:app.contextLength,1);
            Y = app.chainageContext(1:app.contextLength,index);
            if isBM
                Y = Y + app.chainageContext(1:app.contextLength,4);
            end
            
            a = interp1(CH,Y,y,app.GLinter);
            
        end
        
        function C = dataFromY(app,y)
            % check if y out of bound
            if y > app.chainageContext(app.contextLength,1)
                error("y must be with in chainage limit");
            end
            
            k1 = app.ch_data(1:28,y);
            k2 = app.ch_data(1:28,y,true);
            
            C = [y k1(2) k2(3) 0 k1(5:9) k2(10:28)];
            
        end
        
        function [dAcut, dAfill] = sectionArea(app,y)
            C = app.dataFromY(y);
            
            % Chainage
            CH = C(1);
            
            % Width
            b = C(2)
            
            % slopes
            cnl=C(5);
            cnr=C(6);
            fnl=C(7);
            fnr=C(8);
            s=C(9);
            
            % Formation Level
            F = C(3);
            
            % Benchmark
            BM = C(4);
            
            % Horizontal Distance
            % D range 11 - 16
            D = C(11:16);
            X = zeros([1,13]);
            X(1:6) = -flip(D);
            X(7) = 0;
            X(8:13) = D;
            
            % Level
            % 17-22 in reverse, 10, 23-28
            L = zeros([1,13]);
            L(1:6) = flip(C(17:22));
            L(7) = C(10);
            L(8:13) = C(23:28);
            L = L+BM;
            
            % Formation Level
            % calculate formation level on each reading
            % slope = 1:s (H:V)
            
            disp(app.chainageContext(1,:));
            
            FL = F+BM+ X*s;
            X_FL = -b/2:app.dt:b/2;
            FL = interp1(X,FL,X_FL,'linear');
            
            % but we have to account for width, cut slopes and fill slopes
            % b < 2*max(D)
            % we need to find the intersection for each edges with GL
            % if formation edge level < GL -> CUT else FILL
            
            % check wheather cut or fill
            % left edge
            LFL = interp1(X_FL,FL,-b/2,'linear');
            CLGL = interp1(X,L,-b/2,app.GLinter);
            
            LEL = LFL;


            if LFL >= CLGL % FILL
                % use 1:fnl (v:h)
                ml = 1/fnl;
                
                
            else % CUT
                % use 1:cnl (v:h)
                ml = -1/cnl;
            end
            
            i = -b/2;
            LGL = CLGL;
            % assuming FILL
            % - + +
            while (LEL-LGL)*(LFL-CLGL)>= 0
                LEL = LFL + ml*(i+b/2);
                LGL = interp1(X,L,i,app.GLinter);
                %disp((LEL-LGL));
                i = i-app.dt;
            end
            %disp(LEL);
            %disp(i);
            
            FL = [LEL FL];
            X_FL = [i X_FL];
            
            % right edge
            RFL = interp1(X_FL,FL,b/2,'linear');
            CRGL = interp1(X,L,b/2,app.GLinter);
            REL = RFL;
            RGL = CRGL;
            
            if RFL >= CRGL % FILL
                % use 1:fnr (v:h)
                mr = -1/fnr;
            else % CUT
                % use 1:cnr (v:h)
                
                mr = 1/cnr;
            end
            i = b/2;
            while (REL-RGL)*(RFL-CRGL)>= 0
                REL = RFL + mr*(i-b/2);
                RGL = interp1(X,L,i,app.GLinter);
                i = i+app.dt;
            end
            
            %disp(REL);
            %disp(i);
            FL = [FL REL];
            X_FL = [X_FL i];
            
            
            FL = interp1(X_FL,FL,X_FL(1):app.dx:X_FL(length(X_FL)));
            X_FL = X_FL(1):app.dx:X_FL(length(X_FL));
            
            % area calculation (integration)
            
            
            EGL = interp1(X,L,X_FL);
            
            diff = EGL - FL; % positive for cut negative for fill
            
            diff_pos = max(diff,0); % cut
            diff_neg = -min(diff ,0); % fill
            
            dAcut = trapz(X_FL,diff_pos) ;
            dAfill = trapz(X_FL,diff_neg) ;


        end
        
        function [Vcut, Vfill] = volume(app)
            xStart = app.chainageContext(1,1);
            xStop = app.chainageContext(app.contextLength,1);
            disp(xStop);
            x = xStart:app.dy:xStop;
            dAcut = 0*x;
            dAfill = 0*x;
            for i = 1:length(x)
                [dAcut(i),dAfill(i)] = app.sectionArea(x(i));
            end
            
            Vcut = trapz(x,dAcut);
            Vfill = trapz(x,dAfill);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.addNewChainage();
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            [~,idx] = ismember(value,app.ListBox.Items);
            app.currentChainage = idx;
            % show data
            app.loadChainage(idx)
            
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)

            deleteChainage(app,app.ListBox.Value);
           
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            firstRun(app);
            filename = inputSingle(app,'Enter file name:','Load');
            app.csvData = readmatrix(string(filename));
            app.clearListBox();
            app.chainageContext = zeros(app.maxChainageAmount,28);
            app.chainageContext(1,1:28) = app.csvData(1,1:28);
            app.ListBox.Items(1) =   {num2str(app.chainageContext(1,1))};
            [r,c] = size(app.csvData);
            if r > app.maxChainageAmount
                r = app.maxChainageAmount;
            end
            app.contextLength = r;
            for i=2:r
                if app.csvData(i,1) == 0
                    break;
                end
                app.chainageContext(i,1:28) = app.csvData(i,1:28);
                app.ListBox.Items(i) = {num2str(app.chainageContext(i,1))};
            end
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            filename = inputSingle(app,'Enter file name:','save');
            csvwrite(string(filename),app.chainageContext(1:app.contextLength,:));
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.Label_8.Text = num2str(value);
            
            
            % add CR value
            setReading(app,1,'D',value);
            
        end

        % Button pushed function: ContourButton
        function ContourButtonPushed(app, event)
            % extract information from context
            
            N = app.contextLength;
            K = app.contourDensity;
            Xdensity = K;
            XavgDistance = mean(app.chainageContext(1:app.contextLength,16))
            disp(XavgDistance)
            XavgDistance = 2*XavgDistance
            xMaxDistance = min(app.chainageContext(1:app.contextLength,16))
            Ydensity = app.chainageContext(N,1) - app.chainageContext(1,1)
            Ydensity = round(K*Ydensity/XavgDistance);
            
            disp(Ydensity)
            
            distance = zeros([Ydensity,Xdensity]);
            chainage = zeros([Ydensity,Xdensity]);
            level = zeros([Ydensity,Xdensity]);
            
            i = 0;
            
            for y = linspace(app.chainageContext(1:1),app.chainageContext(N,1),Ydensity)
                C = app.dataFromY(y);
                i = i+1;
                % Chainage
                CH = C(1);
                chainage(i,:) = ones([1,Xdensity]) * CH;
                
                
                % Width
                b = C(2);
                
                % slopes
                cnl=C(5);
                cnr=C(6);
                fnl=C(7);
                fnr=C(8);
                s=C(9);
                
                % Formation Level
                F = C(3);
                
                % Benchmark
                BM = C(4);
                
                % Horizontal Distance
                % D range 11 - 16
                D = C(11:16);
                X = zeros([1,13]);
                X(1:6) = -flip(D);
                X(7) = 0;
                X(8:13) = D;
                distance(i,:) = linspace(-xMaxDistance,xMaxDistance,Xdensity); 
                
                % Level
                % 17-22 in reverse, 10, 23-28
                L = zeros([1,13]);
                L(1:6) = flip(C(17:22));
                L(7) = C(10);
                L(8:13) = C(23:28);
                L = L+BM;
                L = interp1(X,L,linspace(-xMaxDistance,xMaxDistance,Xdensity),app.contourInterp);
                level(i,:) = L;
                
                
            end
            disp(app.chainageContext(1:app.contextLength,17:28));
            medianLevel = median(median(app.chainageContext(1:app.contextLength,17:28)+app.chainageContext(1:app.contextLength,4)))
            upperLimit = medianLevel +  medianLevel*app.contourRange/100/2
            upperLimit = round(upperLimit,2);
            lowerLimit = medianLevel -  medianLevel*app.contourRange/100/2
            lowerLimit = round(lowerLimit,2);
            
            lineArray = round(linspace(lowerLimit,upperLimit,app.contourLines),2);
            disp(min(level))
            %writematrix(level,"contourLevel.csv");
            contourf(chainage,distance,level,[-1000 lineArray 1000],'ShowText',"on");
            %colormap("winter")
            xlabel("Chainage"), ylabel("Distance")
            title("Contour map of EGL")
            %contourf(distance,chainage,level,[22 23 24 25 26 27],'ShowText',"on");


        end

        % Button pushed function: SectionButton
        function SectionButtonPushed(app, event)
            
            y = inputSingle(app,"Enter Chainage Value:", "Section");
            y = str2double(y);
            C = app.dataFromY(y);
            
            
            % C will be input
            
            % Chainage
            CH = C(1);
            
            % Width
            b = C(2);
            
            % slopes
            cnl=C(5);
            cnr=C(6);
            fnl=C(7);
            fnr=C(8);
            s=C(9);
            
            % Formation Level
            F = C(3);
            
            % Benchmark
            BM = C(4);
            
            % Horizontal Distance
            % D range 11 - 16
            D = C(11:16);
            X = zeros([1,13]);
            X(1:6) = -flip(D);
            X(7) = 0;
            X(8:13) = D;
            
            % Level
            % 17-22 in reverse, 10, 23-28
            L = zeros([1,13]);
            L(1:6) = flip(C(17:22));
            L(7) = C(10);
            L(8:13) = C(23:28);
            L = L+BM;
            
            % Formation Level
            % calculate formation level on each reading
            % slope = 1:s (H:V)
            
            FL = F+BM+ X*s;
            X_FL = -b/2:.1:b/2;
            FL = interp1(X,FL,X_FL,'linear');
            
            % but we have to account for width, cut slopes and fill slopes
            % b < 2*max(D)
            % we need to find the intersection for each edges with GL
            % if formation edge level < GL -> CUT else FILL
            
            % check wheather cut or fill
            % left edge
            LFL = interp1(X_FL,FL,-b/2,'linear');
            CLGL = interp1(X,L,-b/2,app.GLinter);
            
            LEL = LFL;


            if LFL >= CLGL % FILL
                % use 1:fnl (v:h)
                ml = 1/fnl;
                
                
            else % CUT
                % use 1:cnl (v:h)
                ml = -1/cnl;
            end
            
            i = -b/2;
            LGL = CLGL;
            % assuming FILL
            % - + +
            while (LEL-LGL)*(LFL-CLGL)>= 0
                LEL = LFL + ml*(i+b/2);
                LGL = interp1(X,L,i,app.GLinter);
                %disp((LEL-LGL));
                i = i-app.dt;
            end
            %disp(LEL);
            %disp(i);
            
            FL = [LEL FL];
            X_FL = [i X_FL];
            
            % right edge
            RFL = interp1(X_FL,FL,b/2,'linear');
            CRGL = interp1(X,L,b/2,app.GLinter);
            REL = RFL;
            RGL = CRGL;
            
            if RFL >= CRGL % FILL
                % use 1:fnr (v:h)
                mr = -1/fnr;
            else % CUT
                % use 1:cnr (v:h)
                
                mr = 1/cnr;
            end
            i = b/2;
            while (REL-RGL)*(RFL-CRGL)>= 0
                REL = RFL + mr*(i-b/2);
                RGL = interp1(X,L,i,app.GLinter);
                i = i+app.dt;
            end
            
            %disp(REL);
            %disp(i);
            FL = [FL REL];
            X_FL = [X_FL i];
            
            % plotting
            % graph_change
            plot(X,interp1(X,L,X,app.GLinter),'--b',X_FL,FL,"-r");
            title("Section at chainage: " + num2str(CH));
            xlabel('Distance'), ylabel('Level')
            legend("Existing Ground Level", "Formation Level")
        end

        % Button pushed function: ReportButton
        function ReportButtonPushed(app, event)
            [cut,fill] = app.volume();
            prompt = "Total cut volume: "+num2str(cut)+", Total fill volume: " + num2str(fill);
            msgbox(prompt);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ProfileInfoPanel = uipanel(app.GridLayout3);
            app.ProfileInfoPanel.Title = 'Profile Info';
            app.GridLayout5 = uigridlayout(app.ProfileInfoPanel);
            app.bEditFieldLabel = uilabel(app.GridLayout5);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.GridLayout5, 'numeric');
            app.BMEditFieldLabel = uilabel(app.GridLayout5);
            app.BMEditFieldLabel.Text = 'BM';
            app.BMEditField = uieditfield(app.GridLayout5, 'numeric');
            app.FLEditFieldLabel = uilabel(app.GridLayout5);
            app.FLEditFieldLabel.Text = 'FL';
            app.FLEditField = uieditfield(app.GridLayout5, 'numeric');
            app.sEditFieldLabel = uilabel(app.GridLayout5);
            app.sEditFieldLabel.Text = 's';
            app.sEditField = uieditfield(app.GridLayout5, 'numeric');
            app.cnlEditFieldLabel = uilabel(app.GridLayout5);
            app.cnlEditFieldLabel.Text = 'cnl';
            app.cnlEditField = uieditfield(app.GridLayout5, 'numeric');
            app.cnrEditFieldLabel = uilabel(app.GridLayout5);
            app.cnrEditFieldLabel.Text = 'cnr';
            app.cnrEditField = uieditfield(app.GridLayout5, 'numeric');
            app.fnlEditFieldLabel = uilabel(app.GridLayout5);
            app.fnlEditFieldLabel.Text = 'fnl';
            app.fnlEditField = uieditfield(app.GridLayout5, 'numeric');
            app.fnrEditFieldLabel = uilabel(app.GridLayout5);
            app.fnrEditFieldLabel.Text = 'fnr';
            app.fnrEditField = uieditfield(app.GridLayout5, 'numeric');
            app.DataInputPanel = uipanel(app.GridLayout3);
            app.DataInputPanel.Title = 'Data Input';
            app.DataInputPanel.Visible = 'off';
            app.GridLayout6 = uigridlayout(app.DataInputPanel);
            app.NoLabel = uilabel(app.GridLayout6);
            app.NoLabel.Text = 'No';
            app.DLabel = uilabel(app.GridLayout6);
            app.DLabel.Text = 'D';
            app.LRLabel = uilabel(app.GridLayout6);
            app.LRLabel.Text = 'LR';
            app.RRLabel = uilabel(app.GridLayout6);
            app.RRLabel.Text = 'RR';
            app.Label = uilabel(app.GridLayout6);
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.GridLayout6);
            app.Label_2.Text = '1';
            app.Label_3 = uilabel(app.GridLayout6);
            app.Label_3.Text = '2';
            app.Label_4 = uilabel(app.GridLayout6);
            app.Label_4.Text = '3';
            app.Label_5 = uilabel(app.GridLayout6);
            app.Label_5.Text = '4';
            app.Label_6 = uilabel(app.GridLayout6);
            app.Label_6.Text = '0';
            app.EditField = uieditfield(app.GridLayout6, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Label_8 = uilabel(app.GridLayout6);
            app.Label_8.Text = '0';
            app.Label_9 = uilabel(app.GridLayout6);
            app.Label_9.Text = '5';
            app.Label_10 = uilabel(app.GridLayout6);
            app.Label_10.Text = '6';
            app.EditField_2 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_3 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_5 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_6 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_7 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_9 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_10 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_11 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_13 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_14 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_15 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_17 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_18 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_19 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_21 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_22 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_23 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_25 = uieditfield(app.GridLayout6, 'numeric');
            app.ChainagePanel = uipanel(app.GridLayout2);
            app.ChainagePanel.Title = 'Chainage';
            app.GridLayout7 = uigridlayout(app.ChainagePanel);
            app.AddButton = uibutton(app.GridLayout7, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.ListBox = uilistbox(app.GridLayout7);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.DeleteButton = uibutton(app.GridLayout7, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.LoadButton = uibutton(app.GridLayout4, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.GridLayout4, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SectionButton = uibutton(app.GridLayout4, 'push');
            app.SectionButton.ButtonPushedFcn = createCallbackFcn(app, @SectionButtonPushed, true);
            app.SectionButton.Text = 'Section';
            app.ReportButton = uibutton(app.GridLayout4, 'push');
            app.ReportButton.ButtonPushedFcn = createCallbackFcn(app, @ReportButtonPushed, true);
            app.ReportButton.Text = 'Report';
            app.ContourButton = uibutton(app.GridLayout4, 'push');
            app.ContourButton.ButtonPushedFcn = createCallbackFcn(app, @ContourButtonPushed, true);
            app.ContourButton.Text = 'Contour';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EVCS2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end